classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IPAppUIFigure              matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        OpenMenu                   matlab.ui.container.Menu
        ExitMenu                   matlab.ui.container.Menu
        SaveMenu                   matlab.ui.container.Menu
        UndoMenu                   matlab.ui.container.Menu
        EditMenu                   matlab.ui.container.Menu
        TransformMenu              matlab.ui.container.Menu
        ChangeResolutionMenu       matlab.ui.container.Menu
        FlipHorizontalMenu         matlab.ui.container.Menu
        FlipVerticalMenu           matlab.ui.container.Menu
        GrayToRGBMenu              matlab.ui.container.Menu
        ConvertMenu                matlab.ui.container.Menu
        BlackWhiteMenu             matlab.ui.container.Menu
        IndexedMenu                matlab.ui.container.Menu
        ThresholdingMenu           matlab.ui.container.Menu
        AllimageMenu               matlab.ui.container.Menu
        DynamicMenu                matlab.ui.container.Menu
        CropMenu                   matlab.ui.container.Menu
        AddTextMenu                matlab.ui.container.Menu
        AddFilterMenu              matlab.ui.container.Menu
        AverageMenu                matlab.ui.container.Menu
        GaussienMenu               matlab.ui.container.Menu
        MedianMenu                 matlab.ui.container.Menu
        DetectionMenu              matlab.ui.container.Menu
        EdgeMenu                   matlab.ui.container.Menu
        CannyMenu                  matlab.ui.container.Menu
        SobelMenu                  matlab.ui.container.Menu
        PrewittMenu                matlab.ui.container.Menu
        LaplacianofGaussianMenu    matlab.ui.container.Menu
        AddNoiseMenu               matlab.ui.container.Menu
        SaltPepperMenu             matlab.ui.container.Menu
        PoissanMenu                matlab.ui.container.Menu
        SpeckleMenu                matlab.ui.container.Menu
        LocalvarMenu               matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        TabGroup                   matlab.ui.container.TabGroup
        ColorSegmentationTab       matlab.ui.container.Tab
        DeclineBtn                 matlab.ui.control.Button
        AcceptBtn                  matlab.ui.control.Button
        BlueProfileDropDown        matlab.ui.control.DropDown
        GreenProfileDropDown       matlab.ui.control.DropDown
        RedProfileDropDown         matlab.ui.control.DropDown
        BrightnessTab              matlab.ui.container.Tab
        AcceptBtn_2                matlab.ui.control.Button
        DeclineButton              matlab.ui.control.Button
        jasSlider                  matlab.ui.control.Slider
        ContrastTab                matlab.ui.container.Tab
        DeclineButton_2            matlab.ui.control.Button
        AcceptBtn_3                matlab.ui.control.Button
        contrastSlider             matlab.ui.control.Slider
        ColorBitDepthTab           matlab.ui.container.Tab
        AcceptBtn_4                matlab.ui.control.Button
        DeclineBtn_2               matlab.ui.control.Button
        bitDepthSlider             matlab.ui.control.Slider
        RotateTab                  matlab.ui.container.Tab
        resetall                   matlab.ui.control.Button
        DeclineBtn_3               matlab.ui.control.Button
        AcceptBtn_5                matlab.ui.control.Button
        rotateSlider               matlab.ui.control.Slider
        RGBValuesTab               matlab.ui.container.Tab
        BLUESlider                 matlab.ui.control.Slider
        BLUESliderLabel            matlab.ui.control.Label
        GREENSlider                matlab.ui.control.Slider
        GREENSliderLabel           matlab.ui.control.Label
        REDSlider                  matlab.ui.control.Slider
        REDSliderLabel             matlab.ui.control.Label
        DeclineBtn_4               matlab.ui.control.Button
        AcceptBtn_6                matlab.ui.control.Button
        LoadButton                 matlab.ui.control.Button
        OriginalImagePanel         matlab.ui.container.Panel
        histogram_equalizationBtn  matlab.ui.control.Button
        histogramBtn               matlab.ui.control.Button
        stepBackButton             matlab.ui.control.Button
        imageInfo                  matlab.ui.control.Button
        rgbPlane                   matlab.ui.control.Button
        inputImageAxes             matlab.ui.control.UIAxes
        CenterPanel                matlab.ui.container.Panel
        saveImageBtn               matlab.ui.control.Button
        modifiedImageAxes          matlab.ui.control.UIAxes
        RightPanel                 matlab.ui.container.Panel
        BlueAxes                   matlab.ui.control.UIAxes
        GreenAxes                  matlab.ui.control.UIAxes
        RedAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        inputImage; % vstupny obrazok 
        % update pre vstupny obrazok input = upraveny;
        % Load
        imageList;
        originImage;
        fullPath;
        fullfile;
        Pathname;
        Filename;
        original;  
        hsv_space;
        % Prevody
        inputImage_bw;
        inputImage_binarny;
        inputImage_index;
        % Transformacie
        rozlisenie;
        inputImage_rotate;
        inputImage_otocenie_180;
        inputImage_otocenie_90;
        inputImage_otocenie_i90;
        inputImage_flip_horizontal;
        inputImage_flip_vertical;
        inputImage_orezanie;
        % Šumy
        inputImage_poisson;
        inputImage_gaussian;
        inputImage_salt;
        inputImage_speckle;
        inputImage_localvar;
        %Filtre
        inputImage_gaussian_filter;
        inputImage_median_filter;
        inputImage_unsharp_filter;
        inputImage_average_filter;
        inputImage_disk_filter;
        inputImage_laplacian_filter;
        inputImage_motion_filter;
        inputImage_local_laplacian_filter;
        inputImage_log_filter;
        inputImage_prewitt_filter;
        inputImage_sobel_filter;
        % Segmetation
        rgb_image;
        inputImage_jas;
        RGB2;
        inputImage_crop_image;
        inputImage_bitDepth;
        img2;
        sliderValue;
        inputImage_red_value;
        inputImage_background;
        %jas, kontrast
        inputImage_contrast;
        inputImage_histeq;
        inputImage_auto_contrast;
        inputImage_gamma_correction;
        %Histogram
        inputImage_Red;
        inputImage_Green;
        inputImage_Blue;
        inputImage_pixelCountRed;
        inputImage_pixelCountGreen;
        inputImage_pixelCountBlue;
        inputImage_Red_Hist;
        inputImage_Green_Hist;
        inputImage_Blue_Hist;
        % ostatne
        valueRed2;
        inputImage2;
        lower;
        upper;
        lower2
        upper2;
        rcon;
        originalImage;     
        backgroundImage;
        h;
        d;
        val;
        rgb;
        manualDetectSlider;
    end
    
    methods (Access = private)
       function updateimage(app,imagefile)
            
            % For corn.tif, read the second image in the file
            
                try
                    w = waitbar(0,'Please wait...');
                    steps = 100;
                        for step = 1:steps
                            waitbar(step / steps)
                        end
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.IPAppUIFigure, ME.message, 'Image Error');
                    return;
                end            
       
            
            % Create histograms based on number of color channels
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    app.inputImage=im;
                    app.originalImage=im;
                    imshow( app.inputImage, 'Parent', app.modifiedImageAxes);
                    imshow( app.inputImage, 'Parent', app.inputImageAxes);
                    app.imageList = {app.inputImage};
                    % Plot all histograms with the same data for grayscale
                    createhistograms(app);
                    close(w);
                case 3
                    % Display the truecolor image
                    app.inputImage=im;
                    app.originalImage=im;
                    imshow( app.inputImage, 'Parent', app.modifiedImageAxes);
                    imshow( app.inputImage, 'Parent', app.inputImageAxes);
                    app.imageList = {app.inputImage};
                    % Plot the histograms
                    createhistograms(app);
                    close(w);
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.IPAppUIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
              end


       function createhistograms(app)
    if size(app.inputImage, 3) == 3
        histr = histogram(app.RedAxes, app.inputImage(:,:,1), 'FaceColor',[1 0 0],'EdgeColor', 'none');
        histg = histogram(app.GreenAxes, app.inputImage(:,:,2), 'FaceColor',[0 1 0],'EdgeColor', 'none');
        histb = histogram(app.BlueAxes, app.inputImage(:,:,3), 'FaceColor',[0 0 1],'EdgeColor', 'none');
        title(app.RedAxes, 'Red');
        app.GreenAxes.Visible = 'on';
        app.BlueAxes.Visible = 'on';
        maxr = max(histr.BinCounts);
        maxg = max(histg.BinCounts);
        maxb = max(histb.BinCounts);
        maxcount = max([maxr maxg maxb]);
        app.RedAxes.YLim = [0 maxcount];
        app.RedAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
        app.GreenAxes.YLim = [0 maxcount];
        app.GreenAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
        app.BlueAxes.YLim = [0 maxcount];
        app.BlueAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
    else
        histGray = histogram(app.RedAxes, app.inputImage, 'FaceColor',[0.5 0.5 0.5],'EdgeColor', 'none');
        title(app.RedAxes, 'Gray');
        app.RedAxes.YLim = [0 max(histGray.BinCounts)];
        app.RedAxes.YTick = round([0 max(histGray.BinCounts)/2 max(histGray.BinCounts)], 2, 'significant');
        cla(app.GreenAxes);
        cla(app.BlueAxes);
        app.GreenAxes.Visible = 'off';
        app.BlueAxes.Visible = 'off';
    end
end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            pause(4);
           pathofMLAPP = fileparts(mfilename('fullpath'));
           relativePath = 'imgs\object.jpeg';
            fullPath = fullfile(pathofMLAPP, relativePath);
               updateimage(app, fullPath);
        end

        % Callback function: LoadButton, OpenMenu
        function LoadButtonPushed(app, event)
  
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif;*.jpeg','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
            end
        end

        % Button pushed function: rgbPlane
        function rgbPlaneButtonPushed(app, event)
        w = waitbar(0, 'Please wait...');
        [~,~,D] = size(app.inputImage);
        waitbar(1 / 1)
        if D==3             
            r_plane=app.inputImage(:,:,1);
            g_plane=app.inputImage(:,:,2); 
            b_plane=app.inputImage(:,:,3);   
            figure,subplot(1,3,1)         
            imshow(r_plane); title('RED Plane');
            subplot(1,3,2)
            imshow(g_plane); title('Green Plane');
            subplot(1,3,3)
            imshow(b_plane); title('Blue Plane');
        else
             msgbox('This feature only available for RGB images','Action crashed','error');
        end
        close(w);
        end

        % Button pushed function: imageInfo
        function resetallButtonPushed(app, event)
            %app.inputImage=app.original;
            w = waitbar(0, 'Please wait...');
           app.inputImage = app.imageList{1};
           waitbar(1 / 1);
           imshow(app.inputImage, 'Parent', app.modifiedImageAxes);
           createhistograms(app);
           close(w);
        end

        % Button pushed function: resetall
        function rotateImage(app, event)

app.inputImage_rotate = rotateImage(app.inputImage,90);
imshow(app.inputImage_rotate, 'Parent', app.modifiedImageAxes);
        end

        % Callback function: OriginalImagePanel, UndoMenu, stepBackButton
        function stepBackButtonPushed(app, event)
            % w = waitbar(0, 'Please wait...');
            if numel(app.imageList) > 1 
              app.imageList(end)=[];
              app.inputImage = app.imageList{end};
              imshow(app.inputImage, 'Parent', app.modifiedImageAxes);                     
            end
            waitbar(1 / 1);
            createhistograms(app);
            close(w);
        end

        % Callback function: SaveMenu, saveImageBtn
        function SaveImageMenuSelected(app, event)
            [file, path]= uiputfile({'*.jpg';'*.tif';'*.png';'*.gif';'*.*'}, 'Save As');
            if (ischar(path))
            save=[path file]; 
            imwrite(app.inputImage,save,'jpg' );
           uialert(app.IPAppUIFigure, 'Image saved successfully', 'Success', 'Icon', 'success');
            end
        end

        % Callback function: ExitMenu, IPAppUIFigure
        function ExitMenuSelected(app, event)
            delete(app.IPAppUIFigure);
        end

        % Value changed function: RedProfileDropDown
        function RedProfileDropDownValueChanged(app, event)
            w = waitbar(0, 'Please wait...');
            waitbar(1 / 2);
            redProfileSegmentationModule;
            waitbar(2 / 2);
            close(w);
        end

        % Value changed function: GreenProfileDropDown
        function GreenProfileDropDownValueChanged(app, event)
            w = waitbar(0, 'Please wait...');
            waitbar(1 / 2);
            greenProfileSegmentationModule;
            waitbar(2 / 2);
            close(w);
        end

        % Value changed function: BlueProfileDropDown
        function BlueProfileDropDownValueChanged(app, event)
            w = waitbar(0, 'Please wait...');
            waitbar(1 / 2);
            blueProfileSegmentationModule;
            waitbar(2 / 2);
            close(w);
        end

        % Button pushed function: AcceptBtn, AcceptBtn_2, AcceptBtn_3, 
        % ...and 3 other components
        function AcceptBtnPushed(app, event)
          w = waitbar(0, 'Please wait...');
          waitbar(1 / 2);
            if (app.TabGroup.SelectedTab == app.BrightnessTab)
              app.inputImage = app.inputImage_jas;
              app.jasSlider.Value = 0;  
              app.imageList{end+1} = app.inputImage;
              
          elseif (app.TabGroup.SelectedTab == app.ColorSegmentationTab)
              app.inputImage = app.rgb_image;
              app.imageList{end+1} = app.inputImage;
              
          elseif (app.TabGroup.SelectedTab == app.ColorBitDepthTab)
              app.inputImage = app.inputImage_bitDepth;
              app.bitDepthSlider.Value = 8;
              app.imageList{end+1} = app.inputImage;
              
          elseif (app.TabGroup.SelectedTab == app.RotateTab)
              app.inputImage = app.inputImage_rotate;
              app.rotateSlider.Value = 0;
              app.imageList{end+1} = app.inputImage;
              
          elseif (app.TabGroup.SelectedTab == app.ContrastTab)
              app.inputImage = app.inputImage_contrast;
              app.contrastSlider.Value = 1;
              app.imageList{end+1} = app.inputImage;
              
            elseif (app.TabGroup.SelectedTab == app.RGBValuesTab)
              app.inputImage = app.rcon;
              app.REDSlider.Value = 0;
              app.GREENSlider.Value = 0;
              app.BLUESlider.Value = 0;      
              app.imageList{end+1} = app.inputImage; 

            end  
            waitbar(2 / 2);
          createhistograms(app);
          close(w);
        end

        % Callback function: ColorSegmentationTab, DeclineBtn,
        % DeclineBtn_2, 
        % ...and 4 other components
        function DeclineButton_2Pushed(app, event)
            w = waitbar(0, 'Please wait...');
            
            if (app.TabGroup.SelectedTab == app.BrightnessTab)
                 app.jasSlider.Value = 0;  
             elseif (app.TabGroup.SelectedTab == app.ColorBitDepthTab)
                 app.bitDepthSlider.Value = 8;
             elseif (app.TabGroup.SelectedTab == app.RotateTab)
                 app.rotateSlider.Value = 0;   
             elseif (app.TabGroup.SelectedTab == app.ContrastTab)        
              app.contrastSlider.Value = 1;   
             elseif (app.TabGroup.SelectedTab == app.ColorSegmentationTab)
                 
            elseif (app.TabGroup.SelectedTab == app.RGBValuesTab)
                    app.REDSlider.Value = 0;
                 app.GREENSlider.Value = 0;
                 app.BLUESlider.Value = 0;
              
            end
            waitbar(1 / 2);
            imshow(app.inputImage, 'Parent', app.modifiedImageAxes); 
            createhistograms(app);
            waitbar(2 / 2);
            close(w);
        end

        % Value changed function: jasSlider
        function jasSliderValueChanged(app, event)
            val_jas = app.jasSlider.Value;
        app.inputImage_jas = app.inputImage+val_jas*2;
        imshow(app.inputImage_jas, 'Parent', app.modifiedImageAxes);
        end

        % Callback function: OriginalImagePanel, contrastSlider
        function contrastSliderValueChanged(app, event)
            value_contrast = app.contrastSlider.Value;
            app.inputImage_contrast=app.inputImage.*value_contrast*2;
            imshow(app.inputImage_contrast, 'Parent', app.modifiedImageAxes);
        end

        % Value changed function: bitDepthSlider
        function bitDepthSliderValueChanged(app, event)
            app.sliderValue = app.bitDepthSlider.Value;
            [~,~,D] = size(app.inputImage);   
             if D==3
            noOfLevels =2^app.sliderValue;
            app.img2 = round((double(app.inputImage)/255)*noOfLevels);
            app.inputImage_bitDepth = uint8(double(app.img2)/noOfLevels*255);
            imshow(app.inputImage_bitDepth, 'Parent', app.modifiedImageAxes); 
             else
                    colorsNum2= 2^app.sliderValue;
                    noOfLevels = 2^app.sliderValue;
                    app.img2 = round((double(app.inputImage)/255)*noOfLevels);
                    app.inputImage_bitDepth= uint8(double(app.img2)/noOfLevels*255);
                    imshow(app.inputImage_bitDepth, 'Parent', app.modifiedImageAxes);
             end
        end

        % Callback function: RotateTab, rotateSlider
        function rotateSliderValueChanged(app, event)
value_rotate = app.rotateSlider.Value;
app.inputImage_rotate = rotateImage(app.inputImage, value_rotate);
imshow(app.inputImage_rotate, 'Parent', app.modifiedImageAxes);
            
        end

        % Menu selected function: ChangeResolutionMenu
        function ChangeresolutionMenuSelected(app, event)

            [H,W,~] = size(app.inputImage); 
                prompt = {'Width:','Height:'};
                nometitle = ('Change Resolution');
                dlg_title = nometitle;
                size_wind = [1 50; 1 50]; 
                defaultans = {num2str(H), num2str(W)};
                options.Resize = 'on';
                answer = inputdlg(prompt,dlg_title,size_wind, defaultans,options); 
                odpoved_sirka = str2double(answer{1});  
                odpoved_vyska = str2double(answer{2});
                    if (isempty(answer)) 
                        return;                   
                    end
                   
                    if ((odpoved_sirka<=0) || (odpoved_vyska<=0) ) 
                         msgbox('The entered values are less than zero'...
                             ,'Crash','error'); 
                    end
                    if ( (odpoved_sirka>=W) || (odpoved_vyska>=H))  
                         msgbox('The entered values are more than the resolution'...
                             ,'Crash','error');                                               
                    end          
    app.rozlisenie=imresize(app.inputImage, [odpoved_vyska odpoved_sirka]);
    app.inputImage=app.rozlisenie;
    app.imageList{end+1} = app.inputImage;
    imshow(app.rozlisenie, 'Parent', app.modifiedImageAxes);
     createhistograms(app);
        end

        % Menu selected function: FlipHorizontalMenu
        function FlipHorizontalMenuSelected(app, event)
    app.inputImage_flip_horizontal = flip(app.inputImage,1);
    app.inputImage = app.inputImage_flip_horizontal; 
    app.imageList{end+1} = app.inputImage;
    imshow(app.inputImage_flip_horizontal, 'Parent', app.modifiedImageAxes);
     createhistograms(app);
        end

        % Menu selected function: FlipVerticalMenu
        function FlipVerticalMenuSelected(app, event)
    app.inputImage_flip_horizontal = flip(app.inputImage,2);
    app.inputImage = app.inputImage_flip_horizontal; 
    app.imageList{end+1} = app.inputImage;
    imshow(app.inputImage_flip_horizontal, 'Parent', app.modifiedImageAxes);
     createhistograms(app);
        end

        % Menu selected function: BlackWhiteMenu
        function BlackWhiteMenuSelected(app, event)
            [~,~,D] = size(app.inputImage);   
        if D==3
            app.inputImage_bw= rgb2gray(app.inputImage);    
            app.inputImage = app.inputImage_bw;
            app.imageList{end+1} = app.inputImage;
            imshow(app.inputImage_bw, 'Parent', app.modifiedImageAxes);
        else
            msgbox('Please load an RGB image','Action crashed','error');
        end
         createhistograms(app);
        end

        % Menu selected function: IndexedMenu
        function IndexedMenuSelected(app, event)
             [~,~,D] = size(app.inputImage);
        if D==3          
            RUN = 1;          
               while (RUN==1)
                prompt = {'[ 0-255 ] :'};
                dlg_title = 'Number of colors:';
                num_lines = 1;     
                def = {'8'};              
                answer = inputdlg(prompt,dlg_title,num_lines,def);
                   if (isempty(answer))
                        return;                   
                    end
                odpoved_index_dither = str2double(answer{1});                        
                    if ((odpoved_index_dither>=0) && (odpoved_index_dither<=255))         
                        RUN = 0;                        
                    end                     
                end    
    [app.inputImage_index,map] = rgb2ind(app.inputImage,odpoved_index_dither,'nodither'); %nodither, dither
    app.inputImage = app.inputImage_index; 
    app.imageList{end+1} = app.inputImage;
    imshow(app.inputImage,'Parent', app.modifiedImageAxes);
        else
             msgbox('Please load an RGB image','Action crashed','error');
        end
     createhistograms(app);
        end

        % Value changed function: REDSlider
        function REDSliderValueChanged(app, event)
               
            [~,~,D] = size(app.inputImage);
        if D==3 
            red_value = app.REDSlider.Value;
            r=app.inputImage(:,:,1);
            g=app.inputImage(:,:,2); 
            b=app.inputImage(:,:,3);
            
            r1=r+red_value; 
            app.rcon=cat(3,r1,g,b);
          else
             msgbox('This feature only available for RGB images','Action crashed','error');
        end
        imshow(app.rcon, 'Parent', app.modifiedImageAxes);
        end

        % Value changed function: GREENSlider
        function GREENSliderValueChanged(app, event)
            green_value = app.GREENSlider.Value;
            r=app.inputImage(:,:,1);
            g=app.inputImage(:,:,2); 
            b=app.inputImage(:,:,3);
            g1=g+green_value; app.rcon=cat(3,r,g1,b);
           imshow(app.rcon, 'Parent', app.modifiedImageAxes);
            
        end

        % Value changed function: BLUESlider
        function BLUESliderValueChanged(app, event)
           blue_value = app.BLUESlider.Value;
            r=app.inputImage(:,:,1);
            g=app.inputImage(:,:,2); 
            b=app.inputImage(:,:,3);
           b1=b+blue_value; app.rcon=cat(3,r,g,b1);
           imshow(app.rcon, 'Parent', app.modifiedImageAxes);
            
        end

        % Menu selected function: AllimageMenu
        function seuillageThresholdingAllimageMenuSelected(app, event)
           
            if isempty(app.inputImage)
               errordlg('Please load an image first')
               return
            end
            seuil = inputdlg('enter the seuil value');
            if isempty(seuil)
            else
            seuil = str2double(seuil{1});
            if size(app.inputImage, 3) == 3
            app.inputImage = rgb2gray(app.inputImage);
            end
            filteredImg = app.inputImage > seuil;
            app.inputImage=filteredImg;
            app.imageList{end+1} = app.inputImage;
            imshow(filteredImg, 'Parent', app.modifiedImageAxes);
            end
             createhistograms(app);
            
        end

        % Button pushed function: histogramBtn
        function histogramRGB(app, event)
    I=app.imageList{1};
if size(I, 3) == 3
    % RGB image
    nBins = 256;
    rHist = histcounts(I(:,:,1), nBins);
    gHist = histcounts(I(:,:,2), nBins);
    bHist = histcounts(I(:,:,3), nBins);
    hFig = figure;
    subplot(2,1,1); 
    imshow(I); 
    title('Input image (RGB)');
    subplot(2,1,2);
    app.h(1) = area(1:nBins, rHist, 'FaceColor', 'r'); hold on; 
    app.h(2) = area(1:nBins, gHist, 'FaceColor', 'g'); hold on; 
    app.h(3) = area(1:nBins, bHist, 'FaceColor', 'b'); hold on; 
    title('RGB image histogram');
    xlabel('Pixel Value');
    ylabel('Frequency');
    legend('Red', 'Green', 'Blue');
else
    % Grayscale image
    nBins = 256;
    grayHist = histcounts(I, nBins);
    hFig = figure;
    subplot(2,1,1); 
    imshow(I); 
    title('Input image (Grayscale)');
    subplot(2,1,2);
    app.h(1) = area(1:nBins, grayHist, 'FaceColor', 'k'); hold on; 
    title('Grayscale image histogram');
    xlabel('Pixel Value');
    ylabel('Frequency');
    legend('Intensity');
end
        end

        % Menu selected function: AverageMenu
        function AverageMenuSelected(app, event)
        originalImage = app.inputImage;
            if isempty(originalImage)
               errordlg('Please load an image first')
               return
            end
            m = imnoise(originalImage,'gaussian',0,0.03);
            filterSize = inputdlg('enter the size of moyen filter:');
            if isempty(filterSize)
   
               return
            end
            filterSize = str2double(filterSize{1});
            fw = ones(filterSize) / (filterSize*filterSize);
            filteredImg = imfilter(m,fw);
            app.inputImage=filteredImg;
            app.imageList{end+1} = app.inputImage;
            imshow(app.inputImage, 'Parent', app.modifiedImageAxes);
             createhistograms(app);
        end

        % Menu selected function: GaussienMenu
        function GaussianMenuSelected(app, event)
           originalImage = app.inputImage;
            if isempty(originalImage)
               errordlg('Please load an image first')
               return
            end
            m = imnoise(originalImage,'gaussian',0,0.03);
            filterSize = inputdlg('enter the size of moyen filter:');
            if isempty(filterSize)
   
               return
            end
            sigma = str2double(filterSize{1});
            filteredImg = imgaussfilt(originalImage,sigma);
            app.inputImage=filteredImg;
            app.imageList{end+1} = app.inputImage;
            imshow(app.inputImage, 'Parent', app.modifiedImageAxes);
             createhistograms(app);
            createhistograms(app);
        end

        % Menu selected function: MedianMenu
        function MedianMenuSelected(app, event)
            originalImage = app.inputImage;
            if isempty(originalImage)
               errordlg('Please load an image first')
               return
            end
            m = imnoise(originalImage,'gaussian',0,0.03);
            filterSize = inputdlg('enter the size of moyen filter:');
            if isempty(filterSize)
   
               return
            end
            filterSize = str2double(filterSize{1});
            originalImage = rgb2gray(originalImage);
            filteredImg = medfilt2(originalImage,[filterSize filterSize]);
            app.inputImage=filteredImg;
            app.imageList{end+1} = app.inputImage;
            imshow(app.inputImage, 'Parent', app.modifiedImageAxes);
             createhistograms(app);
             createhistograms(app);
             
        end

        % Button pushed function: histogram_equalizationBtn
        function histogram_equalization(app, event)
             w = waitbar(0, 'Please wait...');
                waitbar(1 / 2);
            histogram_equalization;
               waitbar(2 / 2);
                close(w);

        end

        % Menu selected function: GrayToRGBMenu
        function GrayToRGBMenuSelected(app, event)
           if ndims(app.inputImage) ~= 2
                return
           end
            % map = hot(3000);
            map = parula(100);
            normalizedImage = uint8(interp1(linspace(double(min(app.inputImage(:))), double(max(app.inputImage(:))), size(map, 1)), 1:size(map, 1), double(app.inputImage), 'nearest', 'extrap'));
            rgbImage = ind2rgb(normalizedImage, map);
            app.inputImage = rgbImage;
            app.imageList{end+1} = app.inputImage;
            imshow(app.inputImage,'Parent',app.modifiedImageAxes);
            createhistograms(app);
        end

        % Menu selected function: DynamicMenu
        function dynamicThresholdingMenuSelected(app, event)
            dynamicThresholding;
        end

        % Menu selected function: CannyMenu
        function EdgeMenuSelected(app, event)
            CannyEdgeDetection
        end

        % Menu selected function: SobelMenu
        function EdgeMenuSelected2(app, event)
            SobelEdgeDetection;
        end

        % Menu selected function: PrewittMenu
        function EdgeMenuSelected3(app, event)

inputImage = app.inputImage;
if size(inputImage, 3) == 3
    grayImage = 0.2989 * inputImage(:,:,1) + 0.5870 * inputImage(:,:,2) + 0.1140 * inputImage(:,:,3);
else
    grayImage = inputImage;
end
prewittX = [-1, 0, 1; -1, 0, 1; -1, 0, 1];
prewittY = [-1, -1, -1; 0, 0, 0; 1, 1, 1];
gradientX = conv2(double(grayImage), prewittX, 'same');
gradientY = conv2(double(grayImage), prewittY, 'same');
gradientMagnitude = sqrt(gradientX.^2 + gradientY.^2);
edgeThreshold = 50;
binaryEdges = gradientMagnitude > edgeThreshold;
figure;
imshow(binaryEdges, 'InitialMagnification', 'fit');
title('Prewitt Edges');

        end

        % Menu selected function: LaplacianofGaussianMenu
        function EdgeMenuSelected4(app, event)

inputImage = app.inputImage;
if size(inputImage, 3) == 3
    grayImage = 0.2989 * inputImage(:,:,1) + 0.5870 * inputImage(:,:,2) + 0.1140 * inputImage(:,:,3);
else
    grayImage = inputImage;
end
sigma = 2;
filterSize = 6 * sigma + 1;
[X, Y] = meshgrid(-(filterSize-1)/2:(filterSize-1)/2, -(filterSize-1)/2:(filterSize-1)/2);
gaussianFilter = exp(-(X.^2 + Y.^2) / (2 * sigma^2));
gaussianFilter = gaussianFilter / sum(gaussianFilter(:));
gaussianFiltered = conv2(double(grayImage), gaussianFilter, 'same');
laplacianImage = conv2(double(gaussianFiltered), [1, 1, 1; 1, -8, 1; 1, 1, 1], 'same');
edgeThreshold = 0.01;
binaryEdges = laplacianImage > edgeThreshold;
figure;
imshow(binaryEdges, 'InitialMagnification', 'fit');
title('Laplacian of Gaussian (LoG) Edges');

        end

        % Button down function: IPAppUIFigure
        function AddTextMenuSelected(app, event)
 
        end

        % Menu selected function: CropMenu
        function CropMenuSelected(app, event)

     img = app.inputImage;
    if isempty(img)
               errordlg('Please load an image first')
               return
    end
            %imshow(img, 'Parent', app.modifiedImageAxes);
            rect = getrect(app.modifiedImageAxes);
            x = round(rect(1));
            y = round(rect(2));
            w = round(rect(3));
            h = round(rect(4));
            img_cropped = imcrop(img, [x, y, w, h]);
            
           
            imshow(img_cropped, 'Parent', app.modifiedImageAxes);

        end

        % Menu selected function: SaltPepperMenu
        function SaltPepperMenuSelected(app, event)
             RUN = 1;      
                        while (RUN==1)
                prompt = { 'noise dentisy [0-1] :'};
                dlg_title = 'Enter a input values:';
                size_wind = [1 50];               
                def = {'0.04',}; 
                options.Resize = 'on';
                answer = inputdlg(prompt,dlg_title,size_wind,def,options);
                    if (isempty(answer))
                        return;                   
                    end
                odpoved_saltandpaper_d = str2double(answer{1});                                      
                    if ((odpoved_saltandpaper_d>=0) && (odpoved_saltandpaper_d<=1))                            
                        RUN = 0;                        
                    end                                       
                        end
                        
                    h = waitbar(0,'Please wait...');
                    steps = 200;
                    for step = 1:steps
                        waitbar(step / steps)
                    end
                    close(h)
    app.inputImage_salt = imnoise(app.inputImage,'salt & pepper',odpoved_saltandpaper_d);
    app.inputImage = app.inputImage_salt;
    app.imageList{end+1} = app.inputImage;
    imshow(app.inputImage_salt, 'Parent', app.modifiedImageAxes);
        end

        % Menu selected function: PoissanMenu
        function PoissanMenuSelected(app, event)

                typ_sumu=('poisson');
                app.inputImage_poisson = imnoise(app.inputImage,typ_sumu);
                app.inputImage = app.inputImage_poisson;
                app.imageList{end+1} = app.inputImage;
                imshow(app.inputImage_poisson, 'Parent', app.modifiedImageAxes);
        end

        % Menu selected function: SpeckleMenu
        function SpeckleMenuSelected(app, event)
                     RUN = 1;    
                        while (RUN==1)
                            
                prompt = { 'variance [0-1] :'};
                dlg_title = 'Enter a input values:';
                size_wind = [1 50];                  
                def = {'0.04'}; 
                options.Resize = 'on';  
                answer = inputdlg(prompt,dlg_title,size_wind,def,options);
                    if (isempty(answer))
                        return;                   
                    end
                odpoved_speckle_v = str2double(answer{1}); 
                                       
                    if ((odpoved_speckle_v>=0) && (odpoved_speckle_v<=1))         
                        RUN = 0;                        
                    end  
                    
                        end
        w = waitbar(0,'Please wait...');
        steps = 200;
        for step = 1:steps
            waitbar(step / steps)
        end
    app.inputImage_speckle = imnoise(app.inputImage,'speckle',odpoved_speckle_v);
    app.inputImage = app.inputImage_speckle;
    app.imageList{end+1} = app.inputImage;
    imshow(app.inputImage_speckle, 'Parent', app.modifiedImageAxes);
    close(w)
    
        end

        % Menu selected function: LocalvarMenu
        function LocalvarMenuSelected(app, event)
            app.inputImage_localvar = imnoise(app.inputImage,'localvar',0.05*rand(size(app.inputImage)));
      app.inputImage = app.inputImage_localvar;
      app.imageList{end+1} = app.inputImage;
      imshow(app.inputImage_localvar, 'Parent', app.modifiedImageAxes);
        end

        % Menu selected function: AddTextMenu
        function AddTextMenuSelected2(app, event)
img=app.inputImage
persistent x_pos y_pos;
prompt = {'Enter the text:','Enter the color:','Enter the size'};
dlg_title = 'Add Text';
num_lines = 1;
defaultans = {'Text','black','15'};
answer = inputdlg(prompt,dlg_title,num_lines,defaultans);
text_str = answer{1};
text_color = answer{2};
text_size = answer{3};
imshow(img);
title('choose the position');
[x_pos,y_pos] = ginput(1);
img_with_text = insertText(img,[x_pos y_pos],text_str,'FontSize',str2num(text_size),'BoxOpacity',0,'TextColor',text_color);
close(gcf); 
app.inputImage=img_with_text;
app.imageList{end+1} = app.inputImage;
imshow(img_with_text, 'Parent', app.modifiedImageAxes);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.IPAppUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {573, 573, 573};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {573, 573};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {337, '1x', 312};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.IPAppUIFigure = uifigure('Visible', 'off');
            app.IPAppUIFigure.Name = 'IPApp';
            app.IPAppUIFigure.Icon = fullfile(pathToMLAPP, 'icons', 'app.png');
            app.IPAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.IPAppUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.IPAppUIFigure.ButtonDownFcn = createCallbackFcn(app, @AddTextMenuSelected, true);
            app.FileMenu = uimenu(app.IPAppUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.OpenMenu.Accelerator = 'N';
            app.OpenMenu.Text = 'Open';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Accelerator = 'E';
            app.ExitMenu.Text = 'Exit';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveImageMenuSelected, true);
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = 'Save';
            app.UndoMenu = uimenu(app.FileMenu);
            app.UndoMenu.MenuSelectedFcn = createCallbackFcn(app, @stepBackButtonPushed, true);
            app.UndoMenu.Accelerator = 'Z';
            app.UndoMenu.Text = 'Undo';
            app.EditMenu = uimenu(app.IPAppUIFigure);
            app.EditMenu.Text = 'Edit';
            app.TransformMenu = uimenu(app.EditMenu);
            app.TransformMenu.Text = 'Transform';
            app.ChangeResolutionMenu = uimenu(app.TransformMenu);
            app.ChangeResolutionMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeresolutionMenuSelected, true);
            app.ChangeResolutionMenu.Text = 'Change Resolution';
            app.FlipHorizontalMenu = uimenu(app.TransformMenu);
            app.FlipHorizontalMenu.MenuSelectedFcn = createCallbackFcn(app, @FlipHorizontalMenuSelected, true);
            app.FlipHorizontalMenu.Text = 'Flip Horizontal';
            app.FlipVerticalMenu = uimenu(app.TransformMenu);
            app.FlipVerticalMenu.MenuSelectedFcn = createCallbackFcn(app, @FlipVerticalMenuSelected, true);
            app.FlipVerticalMenu.Text = 'Flip Vertical';
            app.GrayToRGBMenu = uimenu(app.TransformMenu);
            app.GrayToRGBMenu.MenuSelectedFcn = createCallbackFcn(app, @GrayToRGBMenuSelected, true);
            app.GrayToRGBMenu.Text = 'Gray To RGB';
            app.ConvertMenu = uimenu(app.EditMenu);
            app.ConvertMenu.Text = 'Convert';
            app.BlackWhiteMenu = uimenu(app.ConvertMenu);
            app.BlackWhiteMenu.MenuSelectedFcn = createCallbackFcn(app, @BlackWhiteMenuSelected, true);
            app.BlackWhiteMenu.Text = 'Black-White';
            app.IndexedMenu = uimenu(app.ConvertMenu);
            app.IndexedMenu.MenuSelectedFcn = createCallbackFcn(app, @IndexedMenuSelected, true);
            app.IndexedMenu.Text = 'Indexed';
            app.ThresholdingMenu = uimenu(app.EditMenu);
            app.ThresholdingMenu.Text = 'Thresholding';
            app.AllimageMenu = uimenu(app.ThresholdingMenu);
            app.AllimageMenu.MenuSelectedFcn = createCallbackFcn(app, @seuillageThresholdingAllimageMenuSelected, true);
            app.AllimageMenu.Text = 'All image';
            app.DynamicMenu = uimenu(app.ThresholdingMenu);
            app.DynamicMenu.MenuSelectedFcn = createCallbackFcn(app, @dynamicThresholdingMenuSelected, true);
            app.DynamicMenu.Text = 'Dynamic';
            app.CropMenu = uimenu(app.EditMenu);
            app.CropMenu.MenuSelectedFcn = createCallbackFcn(app, @CropMenuSelected, true);
            app.CropMenu.Text = 'Crop';
            app.AddTextMenu = uimenu(app.EditMenu);
            app.AddTextMenu.MenuSelectedFcn = createCallbackFcn(app, @AddTextMenuSelected2, true);
            app.AddTextMenu.Text = 'Add Text';
            app.AddFilterMenu = uimenu(app.IPAppUIFigure);
            app.AddFilterMenu.Text = 'Add Filter';
            app.AverageMenu = uimenu(app.AddFilterMenu);
            app.AverageMenu.MenuSelectedFcn = createCallbackFcn(app, @AverageMenuSelected, true);
            app.AverageMenu.Text = 'Average';
            app.GaussienMenu = uimenu(app.AddFilterMenu);
            app.GaussienMenu.MenuSelectedFcn = createCallbackFcn(app, @GaussianMenuSelected, true);
            app.GaussienMenu.Text = 'Gaussien';
            app.MedianMenu = uimenu(app.AddFilterMenu);
            app.MedianMenu.MenuSelectedFcn = createCallbackFcn(app, @MedianMenuSelected, true);
            app.MedianMenu.Text = 'Median';
            app.DetectionMenu = uimenu(app.IPAppUIFigure);
            app.DetectionMenu.Text = 'Detection';
            app.EdgeMenu = uimenu(app.DetectionMenu);
            app.EdgeMenu.Text = 'Edge';
            app.CannyMenu = uimenu(app.EdgeMenu);
            app.CannyMenu.MenuSelectedFcn = createCallbackFcn(app, @EdgeMenuSelected, true);
            app.CannyMenu.Text = 'Canny';
            app.SobelMenu = uimenu(app.EdgeMenu);
            app.SobelMenu.MenuSelectedFcn = createCallbackFcn(app, @EdgeMenuSelected2, true);
            app.SobelMenu.Text = 'Sobel';
            app.PrewittMenu = uimenu(app.EdgeMenu);
            app.PrewittMenu.MenuSelectedFcn = createCallbackFcn(app, @EdgeMenuSelected3, true);
            app.PrewittMenu.Text = 'Prewitt ';
            app.LaplacianofGaussianMenu = uimenu(app.EdgeMenu);
            app.LaplacianofGaussianMenu.MenuSelectedFcn = createCallbackFcn(app, @EdgeMenuSelected4, true);
            app.LaplacianofGaussianMenu.Text = 'Laplacian of Gaussian';
            app.AddNoiseMenu = uimenu(app.IPAppUIFigure);
            app.AddNoiseMenu.Text = 'Add Noise';
            app.SaltPepperMenu = uimenu(app.AddNoiseMenu);
            app.SaltPepperMenu.MenuSelectedFcn = createCallbackFcn(app, @SaltPepperMenuSelected, true);
            app.SaltPepperMenu.Text = 'Salt-Pepper';
            app.PoissanMenu = uimenu(app.AddNoiseMenu);
            app.PoissanMenu.MenuSelectedFcn = createCallbackFcn(app, @PoissanMenuSelected, true);
            app.PoissanMenu.Text = 'Poissan';
            app.SpeckleMenu = uimenu(app.AddNoiseMenu);
            app.SpeckleMenu.MenuSelectedFcn = createCallbackFcn(app, @SpeckleMenuSelected, true);
            app.SpeckleMenu.Text = 'Speckle';
            app.LocalvarMenu = uimenu(app.AddNoiseMenu);
            app.LocalvarMenu.MenuSelectedFcn = createCallbackFcn(app, @LocalvarMenuSelected, true);
            app.LocalvarMenu.Text = 'Localvar';
            app.GridLayout = uigridlayout(app.IPAppUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.OriginalImagePanel = uipanel(app.LeftPanel);
            app.OriginalImagePanel.Title = 'Original Image';
            app.OriginalImagePanel.SizeChangedFcn = createCallbackFcn(app, @stepBackButtonPushed, true);
            app.OriginalImagePanel.ButtonDownFcn = createCallbackFcn(app, @contrastSliderValueChanged, true);
            app.inputImageAxes = uiaxes(app.OriginalImagePanel);
            app.inputImageAxes.XTick = [];
            app.inputImageAxes.XTickLabel = '';
            app.inputImageAxes.YTick = [];
            app.rgbPlane = uibutton(app.OriginalImagePanel, 'push');
            app.rgbPlane.ButtonPushedFcn = createCallbackFcn(app, @rgbPlaneButtonPushed, true);
            app.rgbPlane.Icon = fullfile(pathToMLAPP, 'icons', 'rgb_lamp.png');
            app.rgbPlane.Text = '';
            app.imageInfo = uibutton(app.OriginalImagePanel, 'push');
            app.imageInfo.ButtonPushedFcn = createCallbackFcn(app, @resetallButtonPushed, true);
            app.imageInfo.Icon = fullfile(pathToMLAPP, 'icons', 'picture.png');
            app.imageInfo.Text = '';
            app.stepBackButton = uibutton(app.OriginalImagePanel, 'push');
            app.stepBackButton.ButtonPushedFcn = createCallbackFcn(app, @stepBackButtonPushed, true);
            app.stepBackButton.Icon = fullfile(pathToMLAPP, 'icons', 'back.png');
            app.stepBackButton.Text = '';
            app.histogramBtn = uibutton(app.OriginalImagePanel, 'push');
            app.histogramBtn.ButtonPushedFcn = createCallbackFcn(app, @histogramRGB, true);
            app.histogramBtn.Icon = fullfile(pathToMLAPP, 'icons', 'histogram.png');
            app.histogramBtn.Text = '';
            app.histogram_equalizationBtn = uibutton(app.OriginalImagePanel, 'push');
            app.histogram_equalizationBtn.ButtonPushedFcn = createCallbackFcn(app, @histogram_equalization, true);
            app.histogram_equalizationBtn.Icon = fullfile(pathToMLAPP, 'icons', 'histogram_equalization.png');
            app.histogram_equalizationBtn.Text = '';
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Custom Image';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.ColorSegmentationTab = uitab(app.TabGroup);
            app.ColorSegmentationTab.SizeChangedFcn = createCallbackFcn(app, @DeclineButton_2Pushed, true);
            app.ColorSegmentationTab.Title = 'Color Segmentation';
            app.RedProfileDropDown = uidropdown(app.ColorSegmentationTab);
            app.RedProfileDropDown.Items = {'Choose RED profile', 'Soft', 'Middle Soft', 'Middle', 'Hard', 'Very Hard'};
            app.RedProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @RedProfileDropDownValueChanged, true);
            app.RedProfileDropDown.Value = 'Choose RED profile';
            app.GreenProfileDropDown = uidropdown(app.ColorSegmentationTab);
            app.GreenProfileDropDown.Items = {'Choose GREEN profile', 'Soft', 'Middle Soft', 'Middle', 'Hard', 'Very Hard'};
            app.GreenProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @GreenProfileDropDownValueChanged, true);
            app.GreenProfileDropDown.Value = 'Choose GREEN profile';
            app.BlueProfileDropDown = uidropdown(app.ColorSegmentationTab);
            app.BlueProfileDropDown.Items = {'Choose BLUE profile', 'Soft', 'Middle Soft', 'Middle', 'Hard', 'Very Hard'};
            app.BlueProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @BlueProfileDropDownValueChanged, true);
            app.BlueProfileDropDown.Value = 'Choose BLUE profile';
            app.AcceptBtn = uibutton(app.ColorSegmentationTab, 'push');
            app.AcceptBtn.ButtonPushedFcn = createCallbackFcn(app, @AcceptBtnPushed, true);
            app.AcceptBtn.Icon = fullfile(pathToMLAPP, 'icons', 'Accept.png');
            app.AcceptBtn.Text = 'Accept';
            app.DeclineBtn = uibutton(app.ColorSegmentationTab, 'push');
            app.DeclineBtn.ButtonPushedFcn = createCallbackFcn(app, @DeclineButton_2Pushed, true);
            app.DeclineBtn.Icon = fullfile(pathToMLAPP, 'icons', 'Decline.png');
            app.DeclineBtn.Text = 'Decline';
            app.BrightnessTab = uitab(app.TabGroup);
            app.BrightnessTab.Title = 'Brightness';
            app.jasSlider = uislider(app.BrightnessTab);
            app.jasSlider.Limits = [-100 100];
            app.jasSlider.ValueChangedFcn = createCallbackFcn(app, @jasSliderValueChanged, true);
            app.DeclineButton = uibutton(app.BrightnessTab, 'push');
            app.DeclineButton.ButtonPushedFcn = createCallbackFcn(app, @DeclineButton_2Pushed, true);
            app.DeclineButton.Icon = fullfile(pathToMLAPP, 'icons', 'Decline.png');
            app.DeclineButton.Text = 'Decline';
            app.AcceptBtn_2 = uibutton(app.BrightnessTab, 'push');
            app.AcceptBtn_2.ButtonPushedFcn = createCallbackFcn(app, @AcceptBtnPushed, true);
            app.AcceptBtn_2.Icon = fullfile(pathToMLAPP, 'icons', 'Accept.png');
            app.AcceptBtn_2.Text = 'Accept';
            app.ContrastTab = uitab(app.TabGroup);
            app.ContrastTab.Title = 'Contrast';
            app.contrastSlider = uislider(app.ContrastTab);
            app.contrastSlider.Limits = [1 10];
            app.contrastSlider.ValueChangedFcn = createCallbackFcn(app, @contrastSliderValueChanged, true);
            app.contrastSlider.Value = 1;
            app.AcceptBtn_3 = uibutton(app.ContrastTab, 'push');
            app.AcceptBtn_3.ButtonPushedFcn = createCallbackFcn(app, @AcceptBtnPushed, true);
            app.AcceptBtn_3.Icon = fullfile(pathToMLAPP, 'icons', 'Accept.png');
            app.AcceptBtn_3.Text = 'Accept';
            app.DeclineButton_2 = uibutton(app.ContrastTab, 'push');
            app.DeclineButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeclineButton_2Pushed, true);
            app.DeclineButton_2.Icon = fullfile(pathToMLAPP, 'icons', 'Decline.png');
            app.DeclineButton_2.Text = 'Decline';
            app.ColorBitDepthTab = uitab(app.TabGroup);
            app.ColorBitDepthTab.Title = 'Color Bit Depth';
            app.bitDepthSlider = uislider(app.ColorBitDepthTab);
            app.bitDepthSlider.Limits = [1 8];
            app.bitDepthSlider.MajorTicks = [1 2 3 4 5 6 7 8];
            app.bitDepthSlider.ValueChangedFcn = createCallbackFcn(app, @bitDepthSliderValueChanged, true);
            app.bitDepthSlider.Value = 8;
            app.DeclineBtn_2 = uibutton(app.ColorBitDepthTab, 'push');
            app.DeclineBtn_2.ButtonPushedFcn = createCallbackFcn(app, @DeclineButton_2Pushed, true);
            app.DeclineBtn_2.Icon = fullfile(pathToMLAPP, 'icons', 'Decline.png');
            app.DeclineBtn_2.Text = 'Decline';
            app.AcceptBtn_4 = uibutton(app.ColorBitDepthTab, 'push');
            app.AcceptBtn_4.ButtonPushedFcn = createCallbackFcn(app, @AcceptBtnPushed, true);
            app.AcceptBtn_4.Icon = fullfile(pathToMLAPP, 'icons', 'Accept.png');
            app.AcceptBtn_4.Text = 'Accept';
            app.RotateTab = uitab(app.TabGroup);
            app.RotateTab.SizeChangedFcn = createCallbackFcn(app, @rotateSliderValueChanged, true);
            app.RotateTab.Title = 'Rotate ';
            app.rotateSlider = uislider(app.RotateTab);
            app.rotateSlider.Limits = [0 360];
            app.rotateSlider.MajorTicks = [0 50 100 150 200 250 300 360];
            app.rotateSlider.ValueChangedFcn = createCallbackFcn(app, @rotateSliderValueChanged, true);
            app.AcceptBtn_5 = uibutton(app.RotateTab, 'push');
            app.AcceptBtn_5.ButtonPushedFcn = createCallbackFcn(app, @AcceptBtnPushed, true);
            app.AcceptBtn_5.Icon = fullfile(pathToMLAPP, 'icons', 'Accept.png');
            app.AcceptBtn_5.Text = 'Accept';
            app.DeclineBtn_3 = uibutton(app.RotateTab, 'push');
            app.DeclineBtn_3.ButtonPushedFcn = createCallbackFcn(app, @DeclineButton_2Pushed, true);
            app.DeclineBtn_3.Icon = fullfile(pathToMLAPP, 'icons', 'Decline.png');
            app.DeclineBtn_3.Text = 'Decline';
            app.resetall = uibutton(app.RotateTab, 'push');
            app.resetall.ButtonPushedFcn = createCallbackFcn(app, @rotateImage, true);
            app.resetall.Icon = fullfile(pathToMLAPP, 'icons', 'circle_around.png');
            app.resetall.Text = 'Rotate with 90';
            app.RGBValuesTab = uitab(app.TabGroup);
            app.RGBValuesTab.Title = 'Change RGB';
            app.AcceptBtn_6 = uibutton(app.RGBValuesTab, 'push');
            app.AcceptBtn_6.ButtonPushedFcn = createCallbackFcn(app, @AcceptBtnPushed, true);
            app.AcceptBtn_6.Icon = fullfile(pathToMLAPP, 'icons', 'Accept.png');
            app.AcceptBtn_6.Text = 'Accept';
            app.DeclineBtn_4 = uibutton(app.RGBValuesTab, 'push');
            app.DeclineBtn_4.ButtonPushedFcn = createCallbackFcn(app, @DeclineButton_2Pushed, true);
            app.DeclineBtn_4.Icon = fullfile(pathToMLAPP, 'icons', 'Decline.png');
            app.DeclineBtn_4.Text = 'Decline';
            app.REDSliderLabel = uilabel(app.RGBValuesTab);
            app.REDSliderLabel.Text = 'RED';
            app.REDSlider = uislider(app.RGBValuesTab);
            app.REDSlider.Limits = [-150 150];
            app.REDSlider.MajorTickLabels = {'-150', '-100', '-50', '0', '50', '100', '150'};
            app.REDSlider.ValueChangedFcn = createCallbackFcn(app, @REDSliderValueChanged, true);
            app.REDSlider.MinorTicks = [];
            app.GREENSliderLabel = uilabel(app.RGBValuesTab);
            app.GREENSliderLabel.Text = 'GREEN';
            app.GREENSlider = uislider(app.RGBValuesTab);
            app.GREENSlider.Limits = [-150 150];
            app.GREENSlider.MajorTickLabels = {'-150', '-100', '-50', '0', '50', '100', '150'};
            app.GREENSlider.ValueChangedFcn = createCallbackFcn(app, @GREENSliderValueChanged, true);
            app.GREENSlider.MinorTicks = [];
            app.BLUESliderLabel = uilabel(app.RGBValuesTab);
            app.BLUESliderLabel.Text = 'BLUE';
            app.BLUESlider = uislider(app.RGBValuesTab);
            app.BLUESlider.Limits = [-150 150];
            app.BLUESlider.MajorTickLabels = {'-150', '-100', '-50', '0', '50', '100', '150'};
            app.BLUESlider.ValueChangedFcn = createCallbackFcn(app, @BLUESliderValueChanged, true);
            app.BLUESlider.MinorTicks = [];
            app.CenterPanel = uipanel(app.GridLayout);
            app.modifiedImageAxes = uiaxes(app.CenterPanel);
            app.modifiedImageAxes.XTick = [];
            app.modifiedImageAxes.YTick = [];
            app.saveImageBtn = uibutton(app.CenterPanel, 'push');
            app.saveImageBtn.ButtonPushedFcn = createCallbackFcn(app, @SaveImageMenuSelected, true);
            app.saveImageBtn.Icon = fullfile(pathToMLAPP, 'icons', 'save.png');
            app.saveImageBtn.Text = 'Save Image';
            app.RightPanel = uipanel(app.GridLayout);
            app.RedAxes = uiaxes(app.RightPanel);
            title(app.RedAxes, 'Red')
            xlabel(app.RedAxes, 'Intensity')
            ylabel(app.RedAxes, 'Pixels')
            app.GreenAxes = uiaxes(app.RightPanel);
            title(app.GreenAxes, 'Green')
            xlabel(app.GreenAxes, 'Intensity')
            ylabel(app.GreenAxes, 'Pixels')
            app.BlueAxes = uiaxes(app.RightPanel);
            title(app.BlueAxes, 'Blue')
            xlabel(app.BlueAxes, 'Intensity')
            ylabel(app.BlueAxes, 'Pixels')
            % Show the figure after all components are created
            app.IPAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IPAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IPAppUIFigure)
        end
    end
end